classdef ViewIsiData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileinfoPanel              matlab.ui.container.Panel
        Anim                       matlab.ui.control.EditField
        AnalyzerdirEditFieldLabel  matlab.ui.control.Label
        Unit                       matlab.ui.control.EditField
        Exp                        matlab.ui.control.EditField
        IsiDir                     matlab.ui.control.EditField
        ISIdatadirEditFieldLabel   matlab.ui.control.Label
        ExperimentEditFieldLabel   matlab.ui.control.Label
        UnitEditFieldLabel         matlab.ui.control.Label
        GetIsi                     matlab.ui.control.Button
        AnaDir                     matlab.ui.control.EditField
        GetAna                     matlab.ui.control.Button
        AnimalEditFieldLabel       matlab.ui.control.Label
        EpochsPanel                matlab.ui.container.Panel
        StimulusLabel              matlab.ui.control.Label
        BaselineLabel              matlab.ui.control.Label
        BaseStart                  matlab.ui.control.NumericEditField
        BaseStop                   matlab.ui.control.NumericEditField
        StimStart                  matlab.ui.control.NumericEditField
        StartsEditField_2Label     matlab.ui.control.Label
        StimStop                   matlab.ui.control.NumericEditField
        StopsEditField_2Label      matlab.ui.control.Label
        StopsEditFieldLabel        matlab.ui.control.Label
        LoadFiles                  matlab.ui.control.Button
        StartsEditFieldLabel       matlab.ui.control.Label
        PlotsettingsPanel          matlab.ui.container.Panel
        PlotVar                    matlab.ui.control.DropDown
        PlotCirc                   matlab.ui.control.CheckBox
        PlotMod                    matlab.ui.control.CheckBox
        ModNr                      matlab.ui.control.NumericEditField
        BlankGroup                 matlab.ui.container.ButtonGroup
        None                       matlab.ui.control.RadioButton
        Blank                      matlab.ui.control.RadioButton
        Cocktail                   matlab.ui.control.RadioButton
        PlotLowpass                matlab.ui.control.CheckBox
        LowNr                      matlab.ui.control.NumericEditField
        ScaleResp                  matlab.ui.control.CheckBox
        PlotAnat                   matlab.ui.control.CheckBox
        PlotAvg                    matlab.ui.control.Button
        VariableDropDownLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        TrialStim %average response per trial, baseline corrected
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetIsi
        function GetIsiButtonPushed(app, event)
            selpath = uigetdir('C:\','Select ISI data directory');
            figure(app.UIFigure);
            if ~isequal(selpath,0)
                app.IsiDir.Value=selpath;
            end
        end

        % Button pushed function: GetAna
        function GetAnaButtonPushed(app, event)
            selpath = uigetdir('C:\','Select Analyzer directory');
            figure(app.UIFigure);
            if ~isequal(selpath,0)
                app.AnaDir.Value=selpath;
            end
        end

        % Button pushed function: LoadFiles
        function LoadFilesButtonPushed(app, event)
            %get basic info
            animalID=app.Anim.Value;
            unitID=app.Unit.Value;
            expID=app.Exp.Value;
            baseIsi=app.IsiDir.Value;
            baseAna=app.AnaDir.Value;

            %make file names
            filebaseIsi=fullfile(baseIsi,animalID,[animalID '_u' unitID '_' expID], [animalID '_u' unitID '_' expID]);
            filenameAna=fullfile(baseAna,animalID,[animalID '_u' unitID '_' expID '.analyzer']);

            %load camera properties
            load([filebaseIsi '_camInfo.mat']); %generates camInfo


            %load analyzer
            if exist(filenameAna,'file')==2
                load(filenameAna,'-mat'); %generates Analyzer
            else
                uialert(app.UIFigure,'Analyzer file not found!','Error','Icon','error');
                return;
            end

            %get number of trials from analyzer
            Nt=getnotrials(Analyzer);            

            %read in files - 1 matlab file per trial
            %we can deal with the assignment to conditions later
            for i=1:Nt
                %read camera data
                load([filebaseIsi '_t' num2str(i) '.mat']); %generates data, metadata and ts
                
                %flag dropped frames
                if size(data,4)~=camInfo.framesPerTrigger
                    dropN=camInfo.framesPerTrigger-size(data,4);
                    disp(['Camera dropped ' num2str(dropN) ' frames in trial ' num2str(i)]);               
                end

                %read logger


            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotsettingsPanel = uipanel(app.UIFigure);
            app.PlotsettingsPanel.Title = 'Plot settings';
            app.VariableDropDownLabel = uilabel(app.PlotsettingsPanel);
            app.VariableDropDownLabel.Text = 'Variable';
            app.PlotAvg = uibutton(app.PlotsettingsPanel, 'push');
            app.PlotAvg.Text = 'Plot average';
            app.PlotAnat = uicheckbox(app.PlotsettingsPanel);
            app.PlotAnat.Text = 'Show anatomy';
            app.ScaleResp = uicheckbox(app.PlotsettingsPanel);
            app.ScaleResp.Text = 'Scale with response';
            app.LowNr = uieditfield(app.PlotsettingsPanel, 'numeric');
            app.LowNr.Value = 5;
            app.PlotLowpass = uicheckbox(app.PlotsettingsPanel);
            app.PlotLowpass.Text = 'Lowpass (px)';
            app.BlankGroup = uibuttongroup(app.PlotsettingsPanel);
            app.BlankGroup.Title = 'Blank correction';
            app.Cocktail = uiradiobutton(app.BlankGroup);
            app.Cocktail.Text = 'Cocktail blank';
            app.Blank = uiradiobutton(app.BlankGroup);
            app.Blank.Text = 'Blank';
            app.None = uiradiobutton(app.BlankGroup);
            app.None.Text = 'None';
            app.None.Value = true;
            app.ModNr = uieditfield(app.PlotsettingsPanel, 'numeric');
            app.ModNr.Value = 180;
            app.PlotMod = uicheckbox(app.PlotsettingsPanel);
            app.PlotMod.Text = 'modulo';
            app.PlotCirc = uicheckbox(app.PlotsettingsPanel);
            app.PlotCirc.Text = 'circular';
            app.PlotVar = uidropdown(app.PlotsettingsPanel);
            app.PlotVar.Items = {};
            app.PlotVar.Value = {};
            app.EpochsPanel = uipanel(app.UIFigure);
            app.EpochsPanel.Title = 'Epochs';
            app.StartsEditFieldLabel = uilabel(app.EpochsPanel);
            app.StartsEditFieldLabel.Text = 'Start (s)';
            app.LoadFiles = uibutton(app.EpochsPanel, 'push');
            app.LoadFiles.ButtonPushedFcn = createCallbackFcn(app, @LoadFilesButtonPushed, true);
            app.LoadFiles.Text = 'Load files';
            app.StopsEditFieldLabel = uilabel(app.EpochsPanel);
            app.StopsEditFieldLabel.Text = 'Stop (s)';
            app.StopsEditField_2Label = uilabel(app.EpochsPanel);
            app.StopsEditField_2Label.Text = 'Stop (s)';
            app.StimStop = uieditfield(app.EpochsPanel, 'numeric');
            app.StimStop.Value = 4;
            app.StartsEditField_2Label = uilabel(app.EpochsPanel);
            app.StartsEditField_2Label.Text = 'Start (s)';
            app.StimStart = uieditfield(app.EpochsPanel, 'numeric');
            app.StimStart.Value = 2;
            app.BaseStop = uieditfield(app.EpochsPanel, 'numeric');
            app.BaseStart = uieditfield(app.EpochsPanel, 'numeric');
            app.BaseStart.Value = -1;
            app.BaselineLabel = uilabel(app.EpochsPanel);
            app.BaselineLabel.Text = 'Baseline';
            app.StimulusLabel = uilabel(app.EpochsPanel);
            app.StimulusLabel.Text = 'Stimulus';
            app.FileinfoPanel = uipanel(app.UIFigure);
            app.FileinfoPanel.Title = 'File info';
            app.AnimalEditFieldLabel = uilabel(app.FileinfoPanel);
            app.AnimalEditFieldLabel.Text = 'Animal';
            app.GetAna = uibutton(app.FileinfoPanel, 'push');
            app.GetAna.ButtonPushedFcn = createCallbackFcn(app, @GetAnaButtonPushed, true);
            app.GetAna.Text = 'Get';
            app.AnaDir = uieditfield(app.FileinfoPanel, 'text');
            app.AnaDir.Value = 'z:\EphysNew\Analyzer';
            app.GetIsi = uibutton(app.FileinfoPanel, 'push');
            app.GetIsi.ButtonPushedFcn = createCallbackFcn(app, @GetIsiButtonPushed, true);
            app.GetIsi.Text = 'Get';
            app.UnitEditFieldLabel = uilabel(app.FileinfoPanel);
            app.UnitEditFieldLabel.Text = 'Unit';
            app.ExperimentEditFieldLabel = uilabel(app.FileinfoPanel);
            app.ExperimentEditFieldLabel.Text = 'Experiment';
            app.ISIdatadirEditFieldLabel = uilabel(app.FileinfoPanel);
            app.ISIdatadirEditFieldLabel.Text = 'ISI data dir';
            app.IsiDir = uieditfield(app.FileinfoPanel, 'text');
            app.IsiDir.Value = 'c:\isidata';
            app.Exp = uieditfield(app.FileinfoPanel, 'text');
            app.Unit = uieditfield(app.FileinfoPanel, 'text');
            app.AnalyzerdirEditFieldLabel = uilabel(app.FileinfoPanel);
            app.AnalyzerdirEditFieldLabel.Text = 'Analyzer dir';
            app.Anim = uieditfield(app.FileinfoPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ViewIsiData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end